classdef RoastGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        MainmenuTab                    matlab.ui.container.Tab
        OptionalSelectT2MRIfileLabel   matlab.ui.control.Label
        SelectT1MRIfileLabel           matlab.ui.control.Label
        T2FileLoc                      matlab.ui.control.EditField
        BrowseButton_2                 matlab.ui.control.Button
        T1FileLoc                      matlab.ui.control.EditField
        BrowseButton                   matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        ElectrodeTabGroup              matlab.ui.container.TabGroup
        ElecTab                        matlab.ui.container.Tab
        PositionEditField              matlab.ui.control.EditField
        PositionEditFieldLabel         matlab.ui.control.Label
        StimulationintensitymAEditField  matlab.ui.control.NumericEditField
        StimulationintensitymAEditFieldLabel  matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        ElecTab_2                      matlab.ui.container.Tab
        ElectrodeconfigurationLabel    matlab.ui.control.Label
        NumberofelectrodesEditField    matlab.ui.control.NumericEditField
        NumberofelectrodesEditFieldLabel  matlab.ui.control.Label
        CaptypeDropDown                matlab.ui.control.DropDown
        CaptypeDropDownLabel           matlab.ui.control.Label
        AdvancedsettingsTab            matlab.ui.container.Tab
        TabGroup4                      matlab.ui.container.TabGroup
        ConductivityvaluesTab          matlab.ui.container.Tab
        SmLabel_8                      matlab.ui.control.Label
        SmLabel_7                      matlab.ui.control.Label
        SmLabel_6                      matlab.ui.control.Label
        SmLabel_5                      matlab.ui.control.Label
        SmLabel_4                      matlab.ui.control.Label
        SmLabel_3                      matlab.ui.control.Label
        SmLabel_2                      matlab.ui.control.Label
        SmLabel                        matlab.ui.control.Label
        ElectrodeEditField             matlab.ui.control.NumericEditField
        ElectrodeEditFieldLabel        matlab.ui.control.Label
        GelEditField                   matlab.ui.control.NumericEditField
        GelEditFieldLabel              matlab.ui.control.Label
        AirEditField                   matlab.ui.control.NumericEditField
        AirEditFieldLabel              matlab.ui.control.Label
        SkinEditField                  matlab.ui.control.NumericEditField
        SkinEditFieldLabel             matlab.ui.control.Label
        BoneEditField                  matlab.ui.control.NumericEditField
        BoneEditFieldLabel             matlab.ui.control.Label
        CerebrospinalfluidEditField    matlab.ui.control.NumericEditField
        CerebrospinalfluidLabel        matlab.ui.control.Label
        GraymatterEditField            matlab.ui.control.NumericEditField
        GraymatterEditFieldLabel       matlab.ui.control.Label
        WhitematterEditField           matlab.ui.control.NumericEditField
        WhitematterEditFieldLabel      matlab.ui.control.Label
        MeshoptionsTab                 matlab.ui.container.Tab
        MaximumvolumeEditField         matlab.ui.control.NumericEditField
        MaximumvolumeEditFieldLabel    matlab.ui.control.Label
        RadiusedgeratioEditField       matlab.ui.control.NumericEditField
        RadiusedgeratioEditFieldLabel  matlab.ui.control.Label
        DistanceboundEditField         matlab.ui.control.NumericEditField
        DistanceboundEditFieldLabel    matlab.ui.control.Label
        AngleboundEditField            matlab.ui.control.NumericEditField
        AngleboundEditFieldLabel       matlab.ui.control.Label
        RadiusboundEditField           matlab.ui.control.NumericEditField
        RadiusboundEditFieldLabel      matlab.ui.control.Label
        ZeropaddingEditField           matlab.ui.control.NumericEditField
        ZeropaddingEditFieldLabel      matlab.ui.control.Label
        ResamplingCheckBox             matlab.ui.control.CheckBox
        SimulationtagEditField         matlab.ui.control.EditField
        SimulationtagEditFieldLabel    matlab.ui.control.Label
        InfoTab                        matlab.ui.container.Tab
        AcknowledgementsLabel          matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        HelpfullinksLabel_2            matlab.ui.control.Label
        Hyperlink3_2                   matlab.ui.control.Hyperlink
        Hyperlink3                     matlab.ui.control.Hyperlink
        Hyperlink2                     matlab.ui.control.Hyperlink
        Hyperlink                      matlab.ui.control.Hyperlink
        RightPanel                     matlab.ui.container.Panel
        TabGroup3                      matlab.ui.container.TabGroup
        ElectrodepositionTab           matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        CurrentprogressTextArea        matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        DynamicElecTab % Description
        DynamicOrientationDropDown
        DynamicOrientationText
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [T1file,T1path] = uigetfile('*.nii')  % Open a T1 MRI file
            app.T1FileLoc.Value = strcat(T1path,T1file);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [T2file,T2path] = uigetfile('*.nii');  % Open a T2 MRI file
            app.T2FileLoc.Value = strcat(T2path,T2file);
        end

        % Value changed function: NumberofelectrodesEditField
        function NumberofelectrodesEditFieldValueChanged(app, event)
            RequiredNumTabs = app.NumberofelectrodesEditField.Value;
            if (mod(RequiredNumTabs, 1) == 0) && (RequiredNumTabs >= 2) % Check value appropriate
                CurrentNumTabs = size(app.DynamicElecTab,2) + 2;
                if RequiredNumTabs > CurrentNumTabs % Create new tab(s)
                    for i=1:(RequiredNumTabs-CurrentNumTabs)
                        app.DynamicElecTab{i+CurrentNumTabs-2} = uitab(app.ElectrodeTabGroup);
                        app.DynamicElecTab{i+CurrentNumTabs-2}.Title = string(i+CurrentNumTabs);
                    end
                elseif RequiredNumTabs < CurrentNumTabs % Remove tab(s)
                    TabsForRemoval = [RequiredNumTabs+1 : CurrentNumTabs];
                    for i = flip(TabsForRemoval)
                        delete(app.DynamicElecTab{i-2});
                    end
                    for i = flip(TabsForRemoval)
                        app.DynamicElecTab(i-2) = [];
                    end
                end
            elseif (RequiredNumTabs < 2)
                uif = uifigure();
                uialert(uif,'Please enter a value of 2 or more in "Number of electrodes"','Warning','Icon','warning');
            else
                uif = uifigure();
                uialert(uif,'Please enter a whole number in "Number of electrodes"','Warning','Icon','warning');
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            if strcmp(value, 'Pad') % Create dropdowm menu for electrode orientation
                app.DynamicOrientationDropDown{1} = uidropdown(app.ElecTab);
                app.DynamicOrientationDropDown{1}.Items = {'Long axis going left/right', 'Long axis going front/back', 'Long axis going up/down'};
                app.DynamicOrientationDropDown{1}.Position = [95 18 175 22];
                app.DynamicOrientationDropDown{1}.Value = 'Long axis going left/right';

                app.DynamicOrientationText = uilabel(app.ElecTab);
                app.DynamicOrientationText.HorizontalAlignment = 'left';
                app.DynamicOrientationText.Position = [8 18 90 22];
                app.DynamicOrientationText.Text = 'Orientation';
            else % Delete dropdowm menu for electrode orientation
                if length(app.DynamicOrientationDropDown)> 0
                    delete(app.DynamicOrientationDropDown{1});
                    app.DynamicOrientationDropDown(1) = [];
                end
            end
        end

        % Value changed function: PositionEditField
        function PositionEditFieldValueChanged(app, event)
            value = app.PositionEditField.Value;
            ElectrodePos = ["nz" "lpa" "rpa" "Fp1", "Fpz", "Fp2", "F7", "F3", "Fz", "F4", "F8", ...
            "T7", "C3", "Cz", "C4", "T8",  "P7", "P3", "Pz", "P4", "P8", "O1", "Oz", "O2"];
            if strcmp(app.CaptypeDropDown.Value, '1020')
                if ~ismember(value, ElectrodePos)
                    uif = uifigure();
                    uialert(uif,'The electrode position you entered was not valid. Please refer to the electrode position image.','Warning','Icon','warning');
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {614, 614};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {314, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.MainmenuTab = uitab(app.TabGroup2);
            app.MainmenuTab.Title = 'Main menu';
            app.CaptypeDropDownLabel = uilabel(app.MainmenuTab);
            app.CaptypeDropDownLabel.Text = 'Cap type';
            app.CaptypeDropDown = uidropdown(app.MainmenuTab);
            app.CaptypeDropDown.Items = {'1010 (default)', '1020', '1005', 'BioSemi', 'EGI'};
            app.CaptypeDropDown.Value = '1010 (default)';
            app.NumberofelectrodesEditFieldLabel = uilabel(app.MainmenuTab);
            app.NumberofelectrodesEditFieldLabel.Text = 'Number of electrodes';
            app.NumberofelectrodesEditField = uieditfield(app.MainmenuTab, 'numeric');
            app.NumberofelectrodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofelectrodesEditFieldValueChanged, true);
            app.NumberofelectrodesEditField.Value = 2;
            app.ElectrodeconfigurationLabel = uilabel(app.MainmenuTab);
            app.ElectrodeconfigurationLabel.Text = 'Electrode configuration';
            app.ElectrodeTabGroup = uitabgroup(app.MainmenuTab);
            app.ElecTab = uitab(app.ElectrodeTabGroup);
            app.ElecTab.Title = '1';
            app.TypeDropDownLabel = uilabel(app.ElecTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.ElecTab);
            app.TypeDropDown.Items = {'Disc (default)', 'Pad', 'Ring'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Disc (default)';
            app.StimulationintensitymAEditFieldLabel = uilabel(app.ElecTab);
            app.StimulationintensitymAEditFieldLabel.Text = 'Stimulation intensity (mA)';
            app.StimulationintensitymAEditField = uieditfield(app.ElecTab, 'numeric');
            app.PositionEditFieldLabel = uilabel(app.ElecTab);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.ElecTab, 'text');
            app.PositionEditField.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldValueChanged, true);
            app.ElecTab_2 = uitab(app.ElectrodeTabGroup);
            app.ElecTab_2.Title = '2';
            app.StartButton = uibutton(app.MainmenuTab, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.MainmenuTab, 'push');
            app.StopButton.Text = 'Stop';
            app.BrowseButton = uibutton(app.MainmenuTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.T1FileLoc = uieditfield(app.MainmenuTab, 'text');
            app.BrowseButton_2 = uibutton(app.MainmenuTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.T2FileLoc = uieditfield(app.MainmenuTab, 'text');
            app.SelectT1MRIfileLabel = uilabel(app.MainmenuTab);
            app.SelectT1MRIfileLabel.Text = 'Select T1 MRI file';
            app.OptionalSelectT2MRIfileLabel = uilabel(app.MainmenuTab);
            app.OptionalSelectT2MRIfileLabel.Text = '(Optional) Select T2 MRI file';
            app.AdvancedsettingsTab = uitab(app.TabGroup2);
            app.AdvancedsettingsTab.Title = 'Advanced settings';
            app.SimulationtagEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.SimulationtagEditFieldLabel.Text = 'Simulation tag';
            app.SimulationtagEditField = uieditfield(app.AdvancedsettingsTab, 'text');
            app.ResamplingCheckBox = uicheckbox(app.AdvancedsettingsTab);
            app.ResamplingCheckBox.Text = 'Resampling';
            app.ZeropaddingEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.ZeropaddingEditFieldLabel.Text = 'Zero padding';
            app.ZeropaddingEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.TabGroup4 = uitabgroup(app.AdvancedsettingsTab);
            app.ConductivityvaluesTab = uitab(app.TabGroup4);
            app.ConductivityvaluesTab.Title = 'Conductivity values';
            app.WhitematterEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.WhitematterEditFieldLabel.Text = 'White matter';
            app.WhitematterEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.WhitematterEditField.Value = 0.126;
            app.GraymatterEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.GraymatterEditFieldLabel.Text = 'Gray matter';
            app.GraymatterEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.GraymatterEditField.Value = 0.276;
            app.CerebrospinalfluidLabel = uilabel(app.ConductivityvaluesTab);
            app.CerebrospinalfluidLabel.Text = 'Cerebrospinal fluid ';
            app.CerebrospinalfluidEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.CerebrospinalfluidEditField.Value = 1.65;
            app.BoneEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.BoneEditFieldLabel.Text = 'Bone';
            app.BoneEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.BoneEditField.Value = 0.01;
            app.SkinEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.SkinEditFieldLabel.Text = 'Skin';
            app.SkinEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.SkinEditField.Value = 0.465;
            app.AirEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.AirEditFieldLabel.Text = 'Air';
            app.AirEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.AirEditField.Value = 2.5e-14;
            app.GelEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.GelEditFieldLabel.Text = 'Gel';
            app.GelEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.GelEditField.Value = 0.3;
            app.ElectrodeEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.ElectrodeEditFieldLabel.Text = 'Electrode';
            app.ElectrodeEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.ElectrodeEditField.Value = 59000000;
            app.SmLabel = uilabel(app.ConductivityvaluesTab);
            app.SmLabel.Text = 'S/m';
            app.SmLabel_2 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_2.Text = 'S/m';
            app.SmLabel_3 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_3.Text = 'S/m';
            app.SmLabel_4 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_4.Text = 'S/m';
            app.SmLabel_5 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_5.Text = 'S/m';
            app.SmLabel_6 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_6.Text = 'S/m';
            app.SmLabel_7 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_7.Text = 'S/m';
            app.SmLabel_8 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_8.Text = 'S/m';
            app.MeshoptionsTab = uitab(app.TabGroup4);
            app.MeshoptionsTab.Title = 'Mesh options';
            app.RadiusboundEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.RadiusboundEditFieldLabel.Text = 'Radius bound';
            app.RadiusboundEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.RadiusboundEditField.Value = 5;
            app.AngleboundEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.AngleboundEditFieldLabel.Text = 'Angle bound';
            app.AngleboundEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.AngleboundEditField.Value = 30;
            app.DistanceboundEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.DistanceboundEditFieldLabel.Text = 'Distance bound';
            app.DistanceboundEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.DistanceboundEditField.Value = 0.3;
            app.RadiusedgeratioEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.RadiusedgeratioEditFieldLabel.Text = 'Radius-edge ratio';
            app.RadiusedgeratioEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.RadiusedgeratioEditField.Value = 3;
            app.MaximumvolumeEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximumvolumeEditFieldLabel.Text = 'Maximum volume';
            app.MaximumvolumeEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximumvolumeEditField.Value = 10;
            app.InfoTab = uitab(app.TabGroup2);
            app.InfoTab.Title = 'Info';
            app.Hyperlink = uihyperlink(app.InfoTab);
            app.Hyperlink.URL = 'https://groups.google.com/g/roast-users';
            app.Hyperlink.Text = 'Roast users Google group';
            app.Hyperlink2 = uihyperlink(app.InfoTab);
            app.Hyperlink2.URL = 'https://github.com/andypotatohy/roast';
            app.Hyperlink2.Text = 'Roast github repository with examples';
            app.Hyperlink3 = uihyperlink(app.InfoTab);
            app.Hyperlink3.Text = 'GUI Roast video tutorial';
            app.Hyperlink3_2 = uihyperlink(app.InfoTab);
            app.Hyperlink3_2.Text = 'GUI Roast github repository';
            app.HelpfullinksLabel_2 = uilabel(app.InfoTab);
            app.HelpfullinksLabel_2.Text = 'Helpful links';
            app.Label = uilabel(app.InfoTab);
            app.Label.Text = {'If you use ROAST in your research, please cite these:'; ''; 'Huang, Y., Datta, A., Bikson, M., Parra, L.C., Realistic '; 'vOlumetric-Approach to Simulate Transcranial Electric Stimulation -- '; 'ROAST -- a fully automated open-source pipeline, Journal of Neural '; 'Engineering, Vol. 16, No. 5, 2019 (prefered reference)'; ''; 'Huang, Y., Datta, A., Bikson, M., Parra, L.C., ROAST: an open-source, '; 'fully-automated, Realistic vOlumetric-Approach-based Simulator for TES, '; 'Proceedings of the 40th Annual International Conference of the IEEE '; 'Engineering in Medicine and Biology Society, Honolulu, HI, July 2018'; ''; 'If you use New York head to run simulation, please also cite the '; 'following:'; 'Huang, Y., Parra, L.C., Haufe, S.,2016. The New York Head - A precise '; 'standardized volume conductor model for EEG source localization and '; 'tES targeting. NeuroImage,140, 150-162'; ''; 'If you also use the targeting feature (roast_target), '; 'please cite these:'; ''; 'Dmochowski, J.P., Datta, A., Bikson, M., Su, Y., Parra, L.C., '; 'Optimized multi-electrode stimulation increases focality and intensity at '; 'target, Journal of Neural Engineering 8 (4), 046011, 2011'; ''; 'Dmochowski, J.P., Datta, A., Huang, Y., Richardson, J.D., Bikson, M., '; 'Fridriksson, J., Parra, L.C., Targeted transcranial direct current '; 'stimulation for rehabilitation after stroke, NeuroImage, 75, 12-19, 2013'; ''; 'Huang, Y., Thomas, C., Datta, A., Parra, L.C., Optimized tDCS for '; 'Targeting Multiple Brain Regions: An Integrated Implementation. '; 'Proceedings of the 40th Annual International Conference of the '; 'IEEE Engineering in Medicine and Biology Society, Honolulu, HI, '; 'July 2018, 3545-3548'; ''; 'ROAST was supported by NIH through grants R01MH111896, '; 'R01MH111439, R01NS095123, R44NS092144, R41NS076123, '; 'and by Soterix Medical Inc.'};
            app.AcknowledgementsLabel = uilabel(app.InfoTab);
            app.AcknowledgementsLabel.Text = 'Acknowledgements';
            app.RightPanel = uipanel(app.GridLayout);
            app.CurrentprogressTextArea = uitextarea(app.RightPanel);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.ElectrodepositionTab = uitab(app.TabGroup3);
            app.ElectrodepositionTab.Title = 'Electrode position';
            app.Image = uiimage(app.ElectrodepositionTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, '1020EEG.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoastGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end